classdef cvApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        SaveMenu                    matlab.ui.container.Menu
        OpenMenu                    matlab.ui.container.Menu
        QuitMenu                    matlab.ui.container.Menu
        SettingsMenu                matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        VisGrid                     matlab.ui.container.GridLayout
        FilterDropDown              matlab.ui.control.DropDown
        FilterDropDownLabel         matlab.ui.control.Label
        VisualizationStyleDropDown  matlab.ui.control.DropDown
        VisualizationStyleDropDownLabel  matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function VisGridFunc(app)
            delete(app.VisGrid.Children)
            val = app.VisualizationStyleDropDown.Value;

            switch val
                case 'Option 1'
                    app.VisGrid.RowHeight = {'1x'};
                    app.VisGrid.ColumnWidth = {'1x'};
                    uiaxes(app.VisGrid);  % Ein Plot
        
                case 'Option 2'
                    app.VisGrid.RowHeight = {'1x'};
                    app.VisGrid.ColumnWidth = {'1x', '1x'};
                    uiaxes(app.VisGrid);
                    uiaxes(app.VisGrid);
        
                case 'Option 3'
                    app.VisGrid.RowHeight = {'1x','1x'};
                    app.VisGrid.ColumnWidth = {'1x','1x'};
                    for i = 1:4
                        uiaxes(app.VisGrid);
                    end
        
                case 'Option 4'
                    % Anpassbarer Inhalt: Plot + Slider + Checkbox etc.
                    app.VisGrid.RowHeight = {'1x', 30};
                    app.VisGrid.ColumnWidth = {'2x', '1x'};
        
                    % Plot
                    ax = uiaxes(app.VisGrid);
                    ax.Layout.Row = 1;
                    ax.Layout.Column = [1 2];
        
                    % Slider
                    slider = uislider(app.VisGrid, 'Limits', [0,100]);
                    slider.Layout.Row = 2;
                    slider.Layout.Column = 1;
        
                    % Checkbox
                    cb = uicheckbox(app.VisGrid, 'Text', 'Enable feature');
                    cb.Layout.Row = 2;
                    cb.Layout.Column = 2;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: VisualizationStyleDropDown
        function VisualizationStyleDropDownValueChanged(app, event)
            value = app.VisualizationStyleDropDown.Value;
            VisGridFunc(app);
            
        end

        % Value changed function: FilterDropDown
        function FilterDropDownValueChanged(app, event)
            value = app.FilterDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.Text = 'Save';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.Text = 'Open';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.Text = 'Quit';
            app.SettingsMenu = uimenu(app.UIFigure);
            app.SettingsMenu.Text = 'Settings';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.VisualizationStyleDropDownLabel = uilabel(app.GridLayout);
            app.VisualizationStyleDropDownLabel.Text = 'Visualization Style';
            app.VisualizationStyleDropDown = uidropdown(app.GridLayout);
            app.VisualizationStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @VisualizationStyleDropDownValueChanged, true);
            app.FilterDropDownLabel = uilabel(app.GridLayout);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.GridLayout);
            app.FilterDropDown.ValueChangedFcn = createCallbackFcn(app, @FilterDropDownValueChanged, true);
            app.VisGrid = uigridlayout(app.GridLayout);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cvApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end